classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ImageIOLabel                matlab.ui.control.Label
        ReleasefileEditFieldLabel   matlab.ui.control.Label
        ReleasefileEditField        matlab.ui.control.EditField
        RecoveryfileEditFieldLabel  matlab.ui.control.Label
        RecoveryfileEditField       matlab.ui.control.EditField
        CellNameEditFieldLabel      matlab.ui.control.Label
        CellNameEditField           matlab.ui.control.EditField
        NoofintervalsLabel          matlab.ui.control.Label
        OKButton                    matlab.ui.control.StateButton
        ConfirmSelectionLabel       matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        OutputinformationLabel      matlab.ui.control.Label
        IntervalsselectedLabel      matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_4                     matlab.ui.control.Image
        Channel1Channel2Channel3Channel4Label  matlab.ui.control.Label
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        Channel1ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_5                matlab.ui.control.RadioButton
        MitochondriaButton          matlab.ui.control.RadioButton
        CytoplasmButton             matlab.ui.control.RadioButton
        NucleusButton               matlab.ui.control.RadioButton
        Channel2ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_6                matlab.ui.control.RadioButton
        MitochondriaButton_2        matlab.ui.control.RadioButton
        CytoplasmButton_2           matlab.ui.control.RadioButton
        NucleusButton_2             matlab.ui.control.RadioButton
        Channel3ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_7                matlab.ui.control.RadioButton
        MitochondriaButton_3        matlab.ui.control.RadioButton
        CytoplasmButton_3           matlab.ui.control.RadioButton
        OKButton_2                  matlab.ui.control.StateButton
        ProceedLabel                matlab.ui.control.Label
        NucleusButton_3             matlab.ui.control.RadioButton
        Channel4ButtonGroup         matlab.ui.container.ButtonGroup
        NoneButton_8                matlab.ui.control.RadioButton
        MitochondriaButton_4        matlab.ui.control.RadioButton
        CytoplasmButton_4           matlab.ui.control.RadioButton
        NucleusButton_4             matlab.ui.control.RadioButton
        Tab2                        matlab.ui.container.Tab
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        lsm_flag = 1 % flags whether files are both of lsm type (=1) or not (=0).
        error_counter = 0;
        intervals = [];
        
        LSMRel
        LSMRec
        TRel
        TRec
        stage
        counter
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            value = app.OKButton.Value;
            app.error_counter = 0;
            app.OutputinformationLabel.Text = 'Output information';
            info_label = app.OutputinformationLabel.Text;
            release_file=app.ReleasefileEditField.Value;
            isempty(release_file)
            recovery_file=app.RecoveryfileEditField.Value;
            if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
               lsm_type_error = ['Error. Release and recovery' newline 'filename(s) cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
               lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
            end
            
            if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                lsm_type_error = ['Error. At least one image' newline 'file does not exist.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label;
            end
            if (isempty(app.CellNameEditField.Value))
               lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            if (isempty(regexp(app.CellNameEditField.Value,'\W')) == 0)
                lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
                app.lsm_flag = 0;
                app.error_counter = app.error_counter +1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.OutputinformationLabel.Text = info_label; 
            end
           
           interval_string = app.TextArea.Value;
           interval_string = interval_string{1}; 
           if (isempty(interval_string)) 
               lsm_type_error = ['Error. No. of intervals cannot be empty.'];
               app.lsm_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.OutputinformationLabel.Text = info_label;
               return
            end
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.intervals = [];
            print_intervals=1;
            value_split=strsplit(interval_string,',');
            for I=1:length(value_split)
                value_num=str2num(value_split{I});
                if(isempty(value_num))
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    print_intervals=0;
                    break
                elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                    lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                    app.lsm_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.OutputinformationLabel.Text = info_label;
                    print_intervals=0;
                    break
                else
                    app.intervals = [app.intervals,value_num];    
                end
            end
            if(isempty(app.intervals) == 0) && (print_intervals == 1)
                integer_string = sprintf('%.0f,' , app.intervals);
                integer_string = integer_string(1:end-1);% strip final comma
                app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
            end
            
            if (app.lsm_flag ==1)
                [app.LSMRel, varargout] = lsmread(app.ReleasefileEditField.Value);
                [app.TRel,ch,~,u,v]=size(app.LSMRel);
                
                [app.LSMRec, varargout] = lsmread(app.RecoveryfileEditField.Value);
                [app.TRec,ch,~,u,v]=size(app.LSMRec);

                app.stage=0;
                app.counter=0;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.ReleasefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReleasefileEditFieldLabel.Text = 'Release file';
            app.ReleasefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReleasefileEditField.Value = 'C:\Documents\release.lsm';
            app.RecoveryfileEditFieldLabel = uilabel(app.LeftPanel);
            app.RecoveryfileEditFieldLabel.Text = 'Recovery file';
            app.RecoveryfileEditField = uieditfield(app.LeftPanel, 'text');
            app.RecoveryfileEditField.Value = 'C:\Documents\recovery.lsm';
            app.CellNameEditFieldLabel = uilabel(app.LeftPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CellNameEditField.Value = 'cell_name';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.OKButton = uibutton(app.LeftPanel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.OutputinformationLabel = uilabel(app.LeftPanel);
            app.OutputinformationLabel.Text = 'Output information';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image_2 = uiimage(app.Tab);
            app.Image_3 = uiimage(app.Tab);
            app.Image_4 = uiimage(app.Tab);
            app.Channel1Channel2Channel3Channel4Label = uilabel(app.Tab);
            app.Channel1Channel2Channel3Channel4Label.Text = '  Channel 1           Channel 2          Channel 3             Channel 4';
            app.FrameSliderLabel = uilabel(app.Tab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.Tab);
            app.Channel1ButtonGroup = uibuttongroup(app.Tab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.Tab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.Tab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.Tab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.OKButton_2 = uibutton(app.Tab, 'state');
            app.OKButton_2.Text = 'OK';
            app.ProceedLabel = uilabel(app.Tab);
            app.ProceedLabel.Text = 'Proceed?';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end